classdef Neuron_Network < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainAxes                   matlab.ui.control.UIAxes
        OpenButton                 matlab.ui.control.Button
        ZoomCheckBox               matlab.ui.control.CheckBox
        PreprocessPanel            matlab.ui.container.Panel
        scaleofresizeLabel         matlab.ui.control.Label
        scale                      matlab.ui.control.NumericEditField
        level_hist_adjust          matlab.ui.control.Label
        level_low                  matlab.ui.control.NumericEditField
        disksizeofenhancingcontrastLabel  matlab.ui.control.Label
        disk_size                  matlab.ui.control.NumericEditField
        Run_preprocess             matlab.ui.control.Button
        level_high                 matlab.ui.control.NumericEditField
        Label_range                matlab.ui.control.Label
        parameters_removing        matlab.ui.control.Label
        label_threLabel            matlab.ui.control.Label
        bw_min                     matlab.ui.control.NumericEditField
        intensity_threLabel        matlab.ui.control.Label
        intensity_thre             matlab.ui.control.NumericEditField
        extent_threLabel           matlab.ui.control.Label
        extent_thre                matlab.ui.control.NumericEditField
        area_threLabel             matlab.ui.control.Label
        area_thre                  matlab.ui.control.NumericEditField
        binary_threLabel           matlab.ui.control.Label
        binary_thre                matlab.ui.control.NumericEditField
        min_pixel_numLabel         matlab.ui.control.Label
        min_p_num                  matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        NeurondetectionPanel       matlab.ui.container.Panel
        min_radiusLabel            matlab.ui.control.Label
        R_min                      matlab.ui.control.NumericEditField
        neuron_detect              matlab.ui.control.Button
        density_thresholdLabel     matlab.ui.control.Label
        density_thre               matlab.ui.control.NumericEditField
        anglethresholdLabel        matlab.ui.control.Label
        angle_thre                 matlab.ui.control.NumericEditField
        around_radiusLabel         matlab.ui.control.Label
        r_around                   matlab.ui.control.NumericEditField
        max_radiusLabel            matlab.ui.control.Label
        R_max                      matlab.ui.control.NumericEditField
        aroundthresholdLabel       matlab.ui.control.Label
        around_thre                matlab.ui.control.NumericEditField
        synapsedetectionPanel      matlab.ui.container.Panel
        Run_synapse                matlab.ui.control.Button
        linkdistanceLabel          matlab.ui.control.Label
        lin_dis                    matlab.ui.control.NumericEditField
        linkdegreedifferenceLabel  matlab.ui.control.Label
        lin_degree                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        img = [];
        BW = [];
        img_new = [];
        Neurons = [];
        R = [];
        grades = [];
        connected = [];
        synapse = [];
        breadth = [];
    end

    methods (Access = private)
    
        function draw_neurons(app)
            image(app.MainAxes, app.img_new);
            hold(app.MainAxes, 'on');
            % plot neurons
            plot(app.MainAxes, app.Neurons(:,1),app.Neurons(:,2),'.','color','red', 'MarkerSize', 8);
            [M, N] = size(app.BW);
            num = length(app.R);
            % plot circles
            for k = 1:num
                % generate meshgrid
                annulus = 1;
                row = max(1, app.Neurons(k,1) - app.R(k) - annulus):...
                    min(N, app.Neurons(k,1) + app.R(k) + annulus);
                col = max(1, app.Neurons(k,2) - app.R(k) - annulus)...
                    :min(M, app.Neurons(k,2) + app.R(k) + annulus);
                [area_x, area_y] = meshgrid(row, col);
                % distance 
                dist = sqrt((area_x - app.Neurons(k,1)).^2 + (area_y - app.Neurons(k,2)).^2);
                % number of points in the circular area of points(k)
                in_circle = dist >= app.R(k) - annulus & dist <= app.R(k) + annulus;
                plot(app.MainAxes, area_x(in_circle), area_y(in_circle), '.','color','red', 'MarkerSize', 3);
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            welcome = imread('welcome.tif');
            image(app.MainAxes, welcome);
            app.Label.Text = 'Welcome';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.Label.Text = 'Select image';
            [filename, pathname] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                '*.*','All Files' });
            if isequal(filename,0) || isequal(pathname,0)
                errordlg('no selected files','Error');
                return;
            else
                file=[pathname,filename];
            end
            app.Label.Text = 'Processing...';
            app.img = imread(file);
            app.img = uint16(app.img);
            [m, n] = size(app.img);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            colormap(app.MainAxes, gray);
            imshow(app.img, [], 'parent', app.MainAxes);
            app.Label.Text = 'leisure';
        end

        % Value changed function: ZoomCheckBox
        function ZoomCheckBoxValueChanged(app, event)
            value = app.ZoomCheckBox.Value;
            if value == 1
                pan(app.MainAxes,'on');
                zoom(app.MainAxes,'on');
            else
                pan(app.MainAxes,'off');
                zoom(app.MainAxes,'off'); 
            end
        end

        % Button pushed function: Run_preprocess
        function Run_preprocessButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            [image_removed, app.BW] = preprocess(app.img, app.scale.Value, ...
                        app.level_low.Value, app.level_high.Value, ...
                        app.disk_size.Value, app.bw_min.Value, ...
                        app.intensity_thre.Value, app.extent_thre.Value, ...
                        app.area_thre.Value, app.binary_thre.Value, ...
                        app.min_p_num.Value);
            app.img_new = image_removed;
            imshow(image_removed, 'parent', app.MainAxes);
            [m, n] = size(image_removed);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            app.Label.Text = 'leisure';
        end

        % Button pushed function: neuron_detect
        function neuron_detectButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            min_radius = app.R_min.Value;
            max_radius = app.R_max.Value;
            density_threshold = app.density_thre.Value:0.05:1;
            % get potential neurons with circular kernel
            potential_neurons = intensive_points(app.BW, min_radius,...
                                        max_radius, density_threshold);
            % detect neurons
            [app.Neurons, app.grades, app.R] = ...
                    IsNeurons_new_4(app.BW, potential_neurons, ...
                    'threshold_angle', app.angle_thre.Value, ...
                    'merge_dis', 2, ...
                    'R_max', max_radius, 'R_min', min_radius, ...
                    'annulus', 3, ...
                    'R_around', app.r_around.Value, ...
                    'threshold_around', app.around_thre.Value);
            % show
            draw_neurons(app);
            hold(app.MainAxes, 'off');
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_synapse
        function Run_synapseButtonPushed(app, event)

            app.Label.Text = 'Processing...';
            theta_thre = app.lin_degree.Value;
            fill_gap = app.lin_dis.Value;
            [app.connected, app.synapse, app.breadth] = ...
                            synapse_detection(app.BW, ...
                          app.Neurons, app.R, theta_thre, fill_gap);
            num = length(app.R);
            draw_neurons(app);
            for i = 1 : num-1
                for j = i+1 : num
                    if app.connected(i, j)
                        len = size(app.synapse{i, j}, 1);
                        bread_path = app.breadth{i, j};
                        path = app.synapse{i, j};
                        for k = 1:len-1
                            plot(app.MainAxes, [path(k, 1), path(k+1, 1)],[path(k, 2), path(k+1, 2)],'LineWidth',2,'Color','green');
                           % text(path(k, 1),path(k, 2),int2str(bread_path(k)),'FontSize',2,'Color','red');
                        end
                    end
                end
            end
            hold(app.MainAxes, 'off');
            app.Label.Text = 'leisure';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainAxes = uiaxes(app.UIFigure);
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZoomCheckBox = uicheckbox(app.UIFigure);
            app.ZoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomCheckBoxValueChanged, true);
            app.ZoomCheckBox.Text = 'Zoom';
            app.PreprocessPanel = uipanel(app.UIFigure);
            app.PreprocessPanel.Title = '1. Preprocess';
            app.scaleofresizeLabel = uilabel(app.PreprocessPanel);
            app.scaleofresizeLabel.Text = 'scale of resize :';
            app.scale = uieditfield(app.PreprocessPanel, 'numeric');
            app.scale.Value = 0.5;
            app.level_hist_adjust = uilabel(app.PreprocessPanel);
            app.level_hist_adjust.Text = 'level of histogram adjustment :';
            app.level_low = uieditfield(app.PreprocessPanel, 'numeric');
            app.level_low.Value = 0.3;
            app.disksizeofenhancingcontrastLabel = uilabel(app.PreprocessPanel);
            app.disksizeofenhancingcontrastLabel.Text = 'disk size of enhancing contrast :';
            app.disk_size = uieditfield(app.PreprocessPanel, 'numeric');
            app.disk_size.Value = 1;
            app.Run_preprocess = uibutton(app.PreprocessPanel, 'push');
            app.Run_preprocess.ButtonPushedFcn = createCallbackFcn(app, @Run_preprocessButtonPushed, true);
            app.Run_preprocess.Text = 'Run';
            app.level_high = uieditfield(app.PreprocessPanel, 'numeric');
            app.level_high.Value = 0.98;
            app.Label_range = uilabel(app.PreprocessPanel);
            app.Label_range.Text = '~';
            app.parameters_removing = uilabel(app.PreprocessPanel);
            app.parameters_removing.Text = 'parameters in removing unconnected neurons :';
            app.label_threLabel = uilabel(app.PreprocessPanel);
            app.label_threLabel.Text = 'label_thre: ';
            app.bw_min = uieditfield(app.PreprocessPanel, 'numeric');
            app.bw_min.Value = 20;
            app.intensity_threLabel = uilabel(app.PreprocessPanel);
            app.intensity_threLabel.Text = 'intensity_thre:';
            app.intensity_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.intensity_thre.Value = 170;
            app.extent_threLabel = uilabel(app.PreprocessPanel);
            app.extent_threLabel.Text = 'extent_thre:';
            app.extent_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.extent_thre.Value = 0.3;
            app.area_threLabel = uilabel(app.PreprocessPanel);
            app.area_threLabel.Text = 'area_thre:';
            app.area_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.area_thre.Value = 300;
            app.binary_threLabel = uilabel(app.PreprocessPanel);
            app.binary_threLabel.Text = 'binary_thre:';
            app.binary_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.binary_thre.Value = 40;
            app.min_pixel_numLabel = uilabel(app.PreprocessPanel);
            app.min_pixel_numLabel.Text = 'min_pixel_num';
            app.min_p_num = uieditfield(app.PreprocessPanel, 'numeric');
            app.min_p_num.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NeurondetectionPanel = uipanel(app.UIFigure);
            app.NeurondetectionPanel.Title = '2. Neuron detection';
            app.min_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.min_radiusLabel.Text = 'min_radius: ';
            app.R_min = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.R_min.Value = 25;
            app.neuron_detect = uibutton(app.NeurondetectionPanel, 'push');
            app.neuron_detect.ButtonPushedFcn = createCallbackFcn(app, @neuron_detectButtonPushed, true);
            app.neuron_detect.Text = 'Run';
            app.density_thresholdLabel = uilabel(app.NeurondetectionPanel);
            app.density_thresholdLabel.Text = 'density_threshold: ';
            app.density_thre = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.density_thre.Value = 0.4;
            app.anglethresholdLabel = uilabel(app.NeurondetectionPanel);
            app.anglethresholdLabel.Text = 'angle threshold: ';
            app.angle_thre = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.angle_thre.Value = 0.65;
            app.around_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.around_radiusLabel.Text = 'around_radius: ';
            app.r_around = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.r_around.Value = 12;
            app.max_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.max_radiusLabel.Text = 'max_radius: ';
            app.R_max = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.R_max.Value = 50;
            app.aroundthresholdLabel = uilabel(app.NeurondetectionPanel);
            app.aroundthresholdLabel.Text = 'around threshold: ';
            app.around_thre = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.around_thre.Value = 0.2;
            app.synapsedetectionPanel = uipanel(app.UIFigure);
            app.synapsedetectionPanel.Title = '3. synapse detection';
            app.Run_synapse = uibutton(app.synapsedetectionPanel, 'push');
            app.Run_synapse.ButtonPushedFcn = createCallbackFcn(app, @Run_synapseButtonPushed, true);
            app.Run_synapse.Text = 'Run';
            app.linkdistanceLabel = uilabel(app.synapsedetectionPanel);
            app.linkdistanceLabel.Text = 'link distance: ';
            app.lin_dis = uieditfield(app.synapsedetectionPanel, 'numeric');
            app.lin_dis.Value = 10;
            app.linkdegreedifferenceLabel = uilabel(app.synapsedetectionPanel);
            app.linkdegreedifferenceLabel.Text = 'link degree difference: ';
            app.lin_degree = uieditfield(app.synapsedetectionPanel, 'numeric');
            app.lin_degree.Value = 15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Neuron_Network

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end