classdef neurons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainAxes                        matlab.ui.control.UIAxes
        OpenButton                      matlab.ui.control.Button
        ZoomCheckBox                    matlab.ui.control.CheckBox
        RefreshButton                   matlab.ui.control.Button
        RESTOREimagetolastactionButton  matlab.ui.control.Button
        thresholdingbackgroundPanel     matlab.ui.container.Panel
        threshold_minEditFieldLabel     matlab.ui.control.Label
        threshold_min                   matlab.ui.control.NumericEditField
        th_min_label                    matlab.ui.control.Label
        threshold_maxEditFieldLabel     matlab.ui.control.Label
        threshold_max                   matlab.ui.control.NumericEditField
        partitionnumberLabel            matlab.ui.control.Label
        partitionx                      matlab.ui.control.NumericEditField
        partitiony                      matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        Run_threshold                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        openandfilterRegionPanel        matlab.ui.container.Panel
        sizeofdiskkernelforopenLabel    matlab.ui.control.Label
        disk_size                       matlab.ui.control.NumericEditField
        numberofmaximalarearegionsLabel  matlab.ui.control.Label
        region_num                      matlab.ui.control.NumericEditField
        minMajorAxisLenLabel            matlab.ui.control.Label
        min_MAL                         matlab.ui.control.NumericEditField
        Run_open_region                 matlab.ui.control.Button
        selectanareatocontinuePanel     matlab.ui.container.Panel
        Run_select                      matlab.ui.control.Button
        partitionnumberLabel_2          matlab.ui.control.Label
        px                              matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        py                              matlab.ui.control.NumericEditField
        selectareaLabel                 matlab.ui.control.Label
        select_x                        matlab.ui.control.NumericEditField
        comma                           matlab.ui.control.Label
        select_y                        matlab.ui.control.NumericEditField
        neuronsdetectPanel              matlab.ui.container.Panel
        num_peaksinHoughTFLabel         matlab.ui.control.Label
        num_peaks                       matlab.ui.control.NumericEditField
        Run_neurons                     matlab.ui.control.Button
        theta_spaceinHoughTFLabel       matlab.ui.control.Label
        theta_space                     matlab.ui.control.NumericEditField
        rho_spaceinHoughTFLabel         matlab.ui.control.Label
        rho_space                       matlab.ui.control.NumericEditField
        minlineslengthLabel             matlab.ui.control.Label
        min_len                         matlab.ui.control.NumericEditField
        extendlineslengthLabel          matlab.ui.control.Label
        ex_len                          matlab.ui.control.NumericEditField
        anglethresholdLabel             matlab.ui.control.Label
        angle_thre                      matlab.ui.control.NumericEditField
        RcenterLabel                    matlab.ui.control.Label
        R_center                        matlab.ui.control.NumericEditField
        RrangeLabel                     matlab.ui.control.Label
        R_range                         matlab.ui.control.NumericEditField
        synapsedetectPanel              matlab.ui.container.Panel
        Run_synapse                     matlab.ui.control.Button
        linkdistanceLabel               matlab.ui.control.Label
        lin_dis                         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        img = [];
        img_before = [];
        Neurons = [];
        R = [];
        grades = [];
        connectivity = [];
        label = [];
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            welcome = imread('welcome.tif');
            image(app.MainAxes, welcome);
            app.Label.Text = 'Welcome';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.Label.Text = 'Select image';
            [filename, pathname] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                '*.*','All Files' });
            if isequal(filename,0) || isequal(pathname,0)
                errordlg('no selected files','Error');
                return;
            else
                file=[pathname,filename];
            end
            app.Label.Text = 'Processing...';
            app.img = imread(file);
            app.img = uint16(app.img);
            [m, n] = size(app.img);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            colormap(app.MainAxes, gray);
            imshow(app.img, [], 'parent', app.MainAxes);
            app.img_before = app.img;
            app.Label.Text = 'leisure';
        end

        % Value changed function: ZoomCheckBox
        function ZoomCheckBoxValueChanged(app, event)
            value = app.ZoomCheckBox.Value;
            if value == 1
                pan(app.MainAxes,'on');
                zoom(app.MainAxes,'on');
            else
                pan(app.MainAxes,'off');
                zoom(app.MainAxes,'off'); 
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            imshow(app.img, 'parent', app.MainAxes);
            [m, n] = size(app.img);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m]; 
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_threshold
        function Run_thresholdButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            [m, n] = size(app.img); 
            imhist(app.img);
            set(gca,'yscale','log');
            M = app.partitionx.Value; 
            N = app.partitiony.Value;
            row = ones(1, M) * m / M;
            col = ones(1, N) * n / N;
            img_parts = mat2cell(app.img, row, col);
            for r = 1:M
                for c = 1:N
                    part_img = img_parts{r,c};
                    hist = imhist(part_img, 2^16);           
                    % peak
                    [~, peak_scale] = max(hist);
                    th_min = peak_scale + app.threshold_min.Value;
                    part_img(part_img < th_min) = th_min;
                    th_max = app.threshold_max.Value;
                    part_img(part_img > th_max) = th_max;
                    part_img = (double(part_img) - th_min) / (th_max - th_min) * 255;
                    img_parts{r,c} = part_img;
                end
            end
            %%
            img_new = cell2mat(img_parts);
            img_new = uint8(img_new);
            imshow(img_new > 0, 'parent', app.MainAxes);
            [m, n] = size(img_new);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            app.img_before = app.img;
            app.img = img_new;
            app.Label.Text = 'leisure';
        end

        % Button pushed function: RESTOREimagetolastactionButton
        function RESTOREimagetolastactionButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            app.img = app.img_before;
            imshow(app.img, 'parent', app.MainAxes);
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_open_region
        function Run_open_regionButtonPushed(app, event)
            % open computation
            app.Label.Text = 'Processing...';
            BW = app.img > 0;
            d_size = app.disk_size.Value;
            se = strel('disk', d_size);
            BW = imopen(BW, se);
            figure; imshow(BW); title('open compution');
            % region_area filter
            max_num = app.region_num.Value;
            BW_out = bwareafilt(BW, max_num);
            figure; imshow(BW_out); title('region area filter');
            % majoraxislength filter
            minL = app.min_MAL.Value;
            BW = filterRegions_MajorAxis(BW_out, minL);
            % show
            imshow(BW, 'parent', app.MainAxes);
            [m, n] = size(BW);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            % save
            app.img_before = app.img;
            app.img = app.img .* uint8(BW);
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_select
        function Run_selectButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            BW = app.img > 0;
            [m, n] = size(BW);
            M = app.px.Value; N = app.py.Value;
            row = ones(1, M) * m / M;
            col = ones(1, N) * n / N;
            % partition
            roi_parts = mat2cell(app.img, row, col);
            BW_parts = mat2cell(BW, row, col);          
            % select and save
            r = app.select_x.Value;
            c = app.select_y.Value;
            app.img = roi_parts{r,c};
            % show
            BW_p = BW_parts{r,c};
            imshow(BW_p, 'parent', app.MainAxes);            
            [m, n] = size(BW_p);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_neurons
        function Run_neuronsButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            % detect lines
            roi = app.img;
            BW = roi > 0;
            [~, lines] = line_detection(roi, app.num_peaks.Value, ...
                            'theta_space', app.theta_space.Value, ...
                            'rho_space', app.rho_space.Value, ...
                            'min_length', app.min_len.Value);
            % extend lines
            extended_lines = lines_extend(lines, app.ex_len.Value);
            % intersect
            points = line_intersect_points(extended_lines, size(BW,1), size(BW,2));
            % draw
            draw_lines(BW, extended_lines); hold on;
            axis on, xlabel x, ylabel y;
            plot(points(:,1),points(:,2),'.','color','blue', 'MarkerSize', 8);
            % detect neurons
            [app.Neurons, app.grades, app.R] = ...
                IsNeurons_new(BW, points, ...
                        'threshold_angle', app.angle_thre.Value, ...
                        'R', app.R_center.Value, ...
                        'R_range', app.R_range.Value);
            % show
            hold(app.MainAxes, 'on');
            imshow(BW, 'parent', app.MainAxes);
            % plot neurons
            plot(app.MainAxes, app.Neurons(:,1),app.Neurons(:,2),'.','color','red', 'MarkerSize', 8);
            [M, N] = size(BW);
            num = length(app.R);
            % plot circles
            for k = 1:num
                % generate meshgrid
                annulus = 1;
                row = max(1, app.Neurons(k,1) - app.R(k) - annulus):...
                    min(N, app.Neurons(k,1) + app.R(k) + annulus);
                col = max(1, app.Neurons(k,2) - app.R(k) - annulus)...
                    :min(M, app.Neurons(k,2) + app.R(k) + annulus);
                [area_x, area_y] = meshgrid(row, col);
                % distance 
                dist = sqrt((area_x - app.Neurons(k,1)).^2 + (area_y - app.Neurons(k,2)).^2);
                % number of points in the circular area of points(k)
                in_circle = dist >= app.R(k) - annulus & dist <= app.R(k) + annulus;
                plot(app.MainAxes, area_x(in_circle), area_y(in_circle), '.','color','red', 'MarkerSize', 1);
            end
            hold(app.MainAxes, 'off');
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_synapse
        function Run_synapseButtonPushed(app, event)
            app.Label.Text = 'leisure';
            [app.connectivity, app.label] = queue_synapse(app.Neurons, app.img, app.R);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainAxes = uiaxes(app.UIFigure);
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZoomCheckBox = uicheckbox(app.UIFigure);
            app.ZoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomCheckBoxValueChanged, true);
            app.ZoomCheckBox.Text = 'Zoom';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RESTOREimagetolastactionButton = uibutton(app.UIFigure, 'push');
            app.RESTOREimagetolastactionButton.ButtonPushedFcn = createCallbackFcn(app, @RESTOREimagetolastactionButtonPushed, true);
            app.RESTOREimagetolastactionButton.Text = {'RESTORE '; 'imageto last action'};
            app.thresholdingbackgroundPanel = uipanel(app.UIFigure);
            app.thresholdingbackgroundPanel.Title = '1. thresholding background';
            app.threshold_minEditFieldLabel = uilabel(app.thresholdingbackgroundPanel);
            app.threshold_minEditFieldLabel.Text = 'threshold_min = ';
            app.threshold_min = uieditfield(app.thresholdingbackgroundPanel, 'numeric');
            app.threshold_min.Value = 256;
            app.th_min_label = uilabel(app.thresholdingbackgroundPanel);
            app.th_min_label.Text = '+  value(peak in hist)';
            app.threshold_maxEditFieldLabel = uilabel(app.thresholdingbackgroundPanel);
            app.threshold_maxEditFieldLabel.Text = 'threshold_max = ';
            app.threshold_max = uieditfield(app.thresholdingbackgroundPanel, 'numeric');
            app.threshold_max.Value = 55000;
            app.partitionnumberLabel = uilabel(app.thresholdingbackgroundPanel);
            app.partitionnumberLabel.Text = 'partition number =';
            app.partitionx = uieditfield(app.thresholdingbackgroundPanel, 'numeric');
            app.partitionx.Value = 25;
            app.partitiony = uieditfield(app.thresholdingbackgroundPanel, 'numeric');
            app.partitiony.Value = 16;
            app.xLabel = uilabel(app.thresholdingbackgroundPanel);
            app.xLabel.Text = 'x';
            app.Run_threshold = uibutton(app.thresholdingbackgroundPanel, 'push');
            app.Run_threshold.ButtonPushedFcn = createCallbackFcn(app, @Run_thresholdButtonPushed, true);
            app.Run_threshold.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.openandfilterRegionPanel = uipanel(app.UIFigure);
            app.openandfilterRegionPanel.Title = '2. open and filterRegion';
            app.sizeofdiskkernelforopenLabel = uilabel(app.openandfilterRegionPanel);
            app.sizeofdiskkernelforopenLabel.Text = 'size of disk kernel for open: ';
            app.disk_size = uieditfield(app.openandfilterRegionPanel, 'numeric');
            app.disk_size.Value = 2;
            app.numberofmaximalarearegionsLabel = uilabel(app.openandfilterRegionPanel);
            app.numberofmaximalarearegionsLabel.Text = 'number of maximal-area regions:   ';
            app.region_num = uieditfield(app.openandfilterRegionPanel, 'numeric');
            app.region_num.Value = 5000;
            app.minMajorAxisLenLabel = uilabel(app.openandfilterRegionPanel);
            app.minMajorAxisLenLabel.Text = 'min MajorAxisLen: ';
            app.min_MAL = uieditfield(app.openandfilterRegionPanel, 'numeric');
            app.min_MAL.Value = 20;
            app.Run_open_region = uibutton(app.openandfilterRegionPanel, 'push');
            app.Run_open_region.ButtonPushedFcn = createCallbackFcn(app, @Run_open_regionButtonPushed, true);
            app.Run_open_region.Text = 'Run';
            app.selectanareatocontinuePanel = uipanel(app.UIFigure);
            app.selectanareatocontinuePanel.Title = '3. select an area to continue';
            app.Run_select = uibutton(app.selectanareatocontinuePanel, 'push');
            app.Run_select.ButtonPushedFcn = createCallbackFcn(app, @Run_selectButtonPushed, true);
            app.Run_select.Text = 'Run';
            app.partitionnumberLabel_2 = uilabel(app.selectanareatocontinuePanel);
            app.partitionnumberLabel_2.Text = 'partition number =';
            app.px = uieditfield(app.selectanareatocontinuePanel, 'numeric');
            app.px.Value = 5;
            app.xLabel_2 = uilabel(app.selectanareatocontinuePanel);
            app.xLabel_2.Text = 'x';
            app.py = uieditfield(app.selectanareatocontinuePanel, 'numeric');
            app.py.Value = 5;
            app.selectareaLabel = uilabel(app.selectanareatocontinuePanel);
            app.selectareaLabel.Text = 'select area =';
            app.select_x = uieditfield(app.selectanareatocontinuePanel, 'numeric');
            app.select_x.Value = 4;
            app.comma = uilabel(app.selectanareatocontinuePanel);
            app.comma.Text = ',';
            app.select_y = uieditfield(app.selectanareatocontinuePanel, 'numeric');
            app.select_y.Value = 3;
            app.neuronsdetectPanel = uipanel(app.UIFigure);
            app.neuronsdetectPanel.Title = '4. neurons detect';
            app.num_peaksinHoughTFLabel = uilabel(app.neuronsdetectPanel);
            app.num_peaksinHoughTFLabel.Text = 'num_peaks in Hough TF: ';
            app.num_peaks = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.num_peaks.Value = 800;
            app.Run_neurons = uibutton(app.neuronsdetectPanel, 'push');
            app.Run_neurons.ButtonPushedFcn = createCallbackFcn(app, @Run_neuronsButtonPushed, true);
            app.Run_neurons.Text = 'Run';
            app.theta_spaceinHoughTFLabel = uilabel(app.neuronsdetectPanel);
            app.theta_spaceinHoughTFLabel.Text = 'theta_space in Hough TF: ';
            app.theta_space = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.theta_space.Value = 2;
            app.rho_spaceinHoughTFLabel = uilabel(app.neuronsdetectPanel);
            app.rho_spaceinHoughTFLabel.Text = 'rho_space in Hough TF: ';
            app.rho_space = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.rho_space.Value = 2;
            app.minlineslengthLabel = uilabel(app.neuronsdetectPanel);
            app.minlineslengthLabel.Text = 'min lines'' length: ';
            app.min_len = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.min_len.Value = 50;
            app.extendlineslengthLabel = uilabel(app.neuronsdetectPanel);
            app.extendlineslengthLabel.Text = 'extend lines'' length: ';
            app.ex_len = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.ex_len.Value = 150;
            app.anglethresholdLabel = uilabel(app.neuronsdetectPanel);
            app.anglethresholdLabel.Text = 'angle threshold: ';
            app.angle_thre = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.angle_thre.Value = 0.7;
            app.RcenterLabel = uilabel(app.neuronsdetectPanel);
            app.RcenterLabel.Text = 'R center: ';
            app.R_center = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.R_center.Value = 75;
            app.RrangeLabel = uilabel(app.neuronsdetectPanel);
            app.RrangeLabel.Text = 'R range: ';
            app.R_range = uieditfield(app.neuronsdetectPanel, 'numeric');
            app.R_range.Value = 30;
            app.synapsedetectPanel = uipanel(app.UIFigure);
            app.synapsedetectPanel.Title = '5. synapse detect';
            app.Run_synapse = uibutton(app.synapsedetectPanel, 'push');
            app.Run_synapse.ButtonPushedFcn = createCallbackFcn(app, @Run_synapseButtonPushed, true);
            app.Run_synapse.Text = 'Run';
            app.linkdistanceLabel = uilabel(app.synapsedetectPanel);
            app.linkdistanceLabel.Text = 'link distance: ';
            app.lin_dis = uieditfield(app.synapsedetectPanel, 'numeric');
            app.lin_dis.Value = 15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = neurons

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end